classdef NewReport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonthlyReportUIFigure     matlab.ui.Figure
        UITable                   matlab.ui.control.Table
        CreateReportButton        matlab.ui.control.Button
        FilterMonthButton         matlab.ui.control.Button
        SelectMonthDropDownLabel  matlab.ui.control.Label
        SelectMonthDropDown       matlab.ui.control.DropDown
        TitleEditFieldLabel       matlab.ui.control.Label
        TitleEditField            matlab.ui.control.EditField
        xlsxLabel                 matlab.ui.control.Label
        SendReportButton          matlab.ui.control.Button
    end

    
    methods (Access = private)
    
        function updateplot(app)
            % Get Table UI component data
            t = app.UITable.DisplayData;            
            
            % Plot modified data 

        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            updateplot(app);
        end

        % Button pushed function: FilterMonthButton
        function FilterMonthButtonPushed(app, event)
                        % Read table array from file

            conn = database('test','root','');

            value = app.SelectMonthDropDown.Value;   
            
            a = 'December 2020';
            b = 'January 2021';
            
            if isequal(value,a)
            query = ['SELECT * ' ...
           'FROM pemantauan_db.dht11 ' ...
              'WHERE date >= ''2020-12-1'' ' ...
             '	AND date <= ''2020-12-31'''];
            
            elseif isequal(value,b)
            query = ['SELECT * ' ...
           'FROM pemantauan_db.dht11 ' ...
              'WHERE date >= ''2021-1-1'' ' ...
             '	AND date <= ''2021-1-31'''];
            
            else
            end

            
            t = fetch(conn,query);

            % Add data to the Table UI Component
            app.UITable.Data = t;
            
            updateplot(app);       
        end

        % Value changed function: SelectMonthDropDown
        function SelectMonthDropDownValueChanged(app, event)
            value = app.SelectMonthDropDown.Value;            
        end

        % Button pushed function: CreateReportButton
        function CreateReportButtonPushed(app, event)
            conn = database('test','root','');

            value = app.SelectMonthDropDown.Value;   
            
            a = 'December 2020';
            b = 'January 2021';
            
            if isequal(value,a)
            query = ['SELECT * ' ...
           'FROM pemantauan_db.dht11 ' ...
              'WHERE date >= ''2020-12-1'' ' ...
             '	AND date <= ''2020-12-31'''];
            
            elseif isequal(value,b)
            query = ['SELECT * ' ...
           'FROM pemantauan_db.dht11 ' ...
              'WHERE date >= ''2021-1-1'' ' ...
             '	AND date <= ''2021-1-31'''];
            
            else
            end

            
            t = fetch(conn,query);

            % Add data to the Table UI Component
            app.UITable.Data = t;
            
            updateplot(app);                 
           
            z = app.TitleEditField.Value;
            filename = z + ".xlsx";
            writetable(t,filename,'Sheet',1,'Range','D1')
            msgbox('Report had been created successfuly!');             
        end

        % Button pushed function: SendReportButton
        function SendReportButtonPushed(app, event)
            sendmailgui
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MonthlyReportUIFigure = uifigure('Visible', 'off');
            app.MonthlyReportUIFigure.Name = 'Monthly Report';
            app.UITable = uitable(app.MonthlyReportUIFigure);
            app.UITable.ColumnName = {'Date'; 'ID'; 'Humidity'; 'Temperature'; 'Status'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true];
            app.UITable.ColumnEditable = [false false false false];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.CreateReportButton = uibutton(app.MonthlyReportUIFigure, 'push');
            app.CreateReportButton.ButtonPushedFcn = createCallbackFcn(app, @CreateReportButtonPushed, true);
            app.CreateReportButton.Text = 'Create Report';
            app.FilterMonthButton = uibutton(app.MonthlyReportUIFigure, 'push');
            app.FilterMonthButton.ButtonPushedFcn = createCallbackFcn(app, @FilterMonthButtonPushed, true);
            app.FilterMonthButton.Text = {'Filter'; 'Month'};
            app.SelectMonthDropDownLabel = uilabel(app.MonthlyReportUIFigure);
            app.SelectMonthDropDownLabel.Text = 'Select Month';
            app.SelectMonthDropDown = uidropdown(app.MonthlyReportUIFigure);
            app.SelectMonthDropDown.Items = {'December 2020', 'January 2021'};
            app.SelectMonthDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectMonthDropDownValueChanged, true);
            app.SelectMonthDropDown.Value = 'December 2020';
            app.TitleEditFieldLabel = uilabel(app.MonthlyReportUIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.MonthlyReportUIFigure, 'text');
            app.xlsxLabel = uilabel(app.MonthlyReportUIFigure);
            app.xlsxLabel.Text = '.xlsx';
            app.SendReportButton = uibutton(app.MonthlyReportUIFigure, 'push');
            app.SendReportButton.ButtonPushedFcn = createCallbackFcn(app, @SendReportButtonPushed, true);
            app.SendReportButton.Text = {'Send'; 'Report'};
            % Show the figure after all components are created
            app.MonthlyReportUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewReport

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonthlyReportUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonthlyReportUIFigure)
        end
    end
end