classdef dataNew < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RecordedDataUIFigure  matlab.ui.Figure
        UITable               matlab.ui.control.Table
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
    end


    methods (Access = private)
    
        function updateplot(app)
            % Get Table UI component data
            t = app.UITable.DisplayData;            
            
            % Plot modified data 

        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read table array from file
            conn = database('test','root','');
            query = ['SELECT * ' ...
    'FROM pemantauan_db.dht11'];
            t = fetch(conn,query);

            % Add data to the Table UI Component
            app.UITable.Data = t;
            
            % Plot the original data
            x1 = t.temperature;
            plot(app.UIAxes,x1);
            
            x2 = t.humidity;
            plot(app.UIAxes2,x2);
            
            % Plot the data
            updateplot(app);
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            % Update the plots when user sorts the columns of the table
            updateplot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RecordedDataUIFigure = uifigure('Visible', 'off');
            app.RecordedDataUIFigure.Name = 'Recorded Data';
            app.UITable = uitable(app.RecordedDataUIFigure);
            app.UITable.ColumnName = {'Date'; 'ID'; 'Humidity'; 'Temperature'; 'Status'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true true true true];
            app.UITable.ColumnEditable = [false false false false];
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UIAxes = uiaxes(app.RecordedDataUIFigure);
            title(app.UIAxes, 'Temperature')
            ylabel(app.UIAxes, 'Temperature (Celcius)')
            app.UIAxes.LineStyleOrder = {'-'};
            app.UIAxes2 = uiaxes(app.RecordedDataUIFigure);
            title(app.UIAxes2, 'Humidity')
            ylabel(app.UIAxes2, 'Humidity %')
            % Show the figure after all components are created
            app.RecordedDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dataNew

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RecordedDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RecordedDataUIFigure)
        end
    end
end