classdef login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SMARTMONITORINGLOGINUIFigure  matlab.ui.Figure
        Image                         matlab.ui.control.Image
        MONITORINGCENTRELOGINLabel    matlab.ui.control.Label
        USERIDEditFieldLabel          matlab.ui.control.Label
        USERIDEditField               matlab.ui.control.EditField
        PASSWORDEditFieldLabel        matlab.ui.control.Label
        PASSWORDEditField             matlab.ui.control.EditField
        LOGINButton                   matlab.ui.control.Button
        HomeButton                    matlab.ui.control.Button
        REGISTRATIONButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function UITableDisplayDataChanged(app, event)
        
        end

        % Callback function
        function UITableCellEdit(app, event)
            
        end

        % Callback function
        function UITableDisplayDataChanged2(app, event)
            
        end

        % Button pushed function: HomeButton
        function HomeButtonPushed(app, event)
            smartMonitor
            app.SMARTMONITORINGLOGINUIFigure.Visible = 'off';
        end

        % Button pushed function: LOGINButton
        function LOGINButtonPushed(app, event)
            a = app.USERIDEditField.Value;
            b = app.PASSWORDEditField.Value;
            
            conn = database('test','root','');
            v = fetch(conn,'select Name from pemantauan_db.users');
            w = fetch(conn,'select Password from pemantauan_db.users');
            
            v = table2cell(v);
            w = table2cell(w);
            
            [~,User_loc] = ismember(a,v);
            
            if ismember(1,strcmp(a,v))&& ismember(1,strcmp(b,w(User_loc)))
                run('smartMonitor2.mlapp')
                app.SMARTMONITORINGLOGINUIFigure.Visible = 'off';
            else
                msgbox('Wrong User ID or Password');
            end
        end

        % Button pushed function: REGISTRATIONButton
        function REGISTRATIONButtonPushed(app, event)
            registration
            app.SMARTMONITORINGLOGINUIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SMARTMONITORINGLOGINUIFigure = uifigure('Visible', 'off');
            app.SMARTMONITORINGLOGINUIFigure.Name = 'SMART MONITORING - LOGIN';
            app.Image = uiimage(app.SMARTMONITORINGLOGINUIFigure);
            app.Image.ImageSource = 'image1.jpg';
            app.MONITORINGCENTRELOGINLabel = uilabel(app.SMARTMONITORINGLOGINUIFigure);
            app.MONITORINGCENTRELOGINLabel.Text = {'MONITORING CENTRE'; 'LOGIN'};
            app.USERIDEditFieldLabel = uilabel(app.SMARTMONITORINGLOGINUIFigure);
            app.USERIDEditFieldLabel.Text = 'USER ID';
            app.USERIDEditField = uieditfield(app.SMARTMONITORINGLOGINUIFigure, 'text');
            app.PASSWORDEditFieldLabel = uilabel(app.SMARTMONITORINGLOGINUIFigure);
            app.PASSWORDEditFieldLabel.Text = 'PASSWORD';
            app.PASSWORDEditField = uieditfield(app.SMARTMONITORINGLOGINUIFigure, 'text');
            app.LOGINButton = uibutton(app.SMARTMONITORINGLOGINUIFigure, 'push');
            app.LOGINButton.ButtonPushedFcn = createCallbackFcn(app, @LOGINButtonPushed, true);
            app.LOGINButton.Text = 'LOGIN';
            app.HomeButton = uibutton(app.SMARTMONITORINGLOGINUIFigure, 'push');
            app.HomeButton.ButtonPushedFcn = createCallbackFcn(app, @HomeButtonPushed, true);
            app.HomeButton.Text = 'Home';
            app.REGISTRATIONButton = uibutton(app.SMARTMONITORINGLOGINUIFigure, 'push');
            app.REGISTRATIONButton.ButtonPushedFcn = createCallbackFcn(app, @REGISTRATIONButtonPushed, true);
            app.REGISTRATIONButton.Text = 'REGISTRATION';
            % Show the figure after all components are created
            app.SMARTMONITORINGLOGINUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SMARTMONITORINGLOGINUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SMARTMONITORINGLOGINUIFigure)
        end
    end
end