classdef registration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        registrationUIFigure           matlab.ui.Figure
        Image                          matlab.ui.control.Image
        MONITORINGCENTREREGISTRATIONLabel  matlab.ui.control.Label
        SUBMITButton                   matlab.ui.control.Button
        MonitoringCentreButton         matlab.ui.control.Button
        USERIDEditFieldLabel           matlab.ui.control.Label
        USERIDEditField                matlab.ui.control.EditField
        EMAILEditFieldLabel            matlab.ui.control.Label
        EMAILEditField                 matlab.ui.control.EditField
        PASSWORDEditFieldLabel         matlab.ui.control.Label
        PASSWORDEditField              matlab.ui.control.EditField
        CONFIRMPASSWORDEditFieldLabel  matlab.ui.control.Label
        CONFIRMPASSWORDEditField       matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SUBMITButton
        function SUBMITButtonPushed(app, event)
            a =app.USERIDEditField.Value;
            b =app.EMAILEditField.Value;
            c =app.PASSWORDEditField.Value;
            d =app.CONFIRMPASSWORDEditField.Value;
            
            conn = database('test','root','');
            data = table({a},{b},{c}, 'VariableNames',{'Name','Email','Password'});
            sqlwrite(conn, 'pemantauan_db.users', data)
            
            if strcmpi(c,d)
                msgbox('Succesfully Submitted')
            end
        end

        % Button pushed function: MonitoringCentreButton
        function MonitoringCentreButtonPushed(app, event)
            login
            app.registrationUIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.registrationUIFigure = uifigure('Visible', 'off');
            app.registrationUIFigure.Name = 'registration';
            app.Image = uiimage(app.registrationUIFigure);
            app.Image.ImageSource = 'image1.jpg';
            app.MONITORINGCENTREREGISTRATIONLabel = uilabel(app.registrationUIFigure);
            app.MONITORINGCENTREREGISTRATIONLabel.Text = {'MONITORING CENTRE'; 'REGISTRATION'};
            app.SUBMITButton = uibutton(app.registrationUIFigure, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SUBMITButtonPushed, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.MonitoringCentreButton = uibutton(app.registrationUIFigure, 'push');
            app.MonitoringCentreButton.ButtonPushedFcn = createCallbackFcn(app, @MonitoringCentreButtonPushed, true);
            app.MonitoringCentreButton.Text = 'Monitoring Centre';
            app.USERIDEditFieldLabel = uilabel(app.registrationUIFigure);
            app.USERIDEditFieldLabel.Text = 'USER ID';
            app.USERIDEditField = uieditfield(app.registrationUIFigure, 'text');
            app.EMAILEditFieldLabel = uilabel(app.registrationUIFigure);
            app.EMAILEditFieldLabel.Text = 'EMAIL';
            app.EMAILEditField = uieditfield(app.registrationUIFigure, 'text');
            app.PASSWORDEditFieldLabel = uilabel(app.registrationUIFigure);
            app.PASSWORDEditFieldLabel.Text = 'PASSWORD';
            app.PASSWORDEditField = uieditfield(app.registrationUIFigure, 'text');
            app.CONFIRMPASSWORDEditFieldLabel = uilabel(app.registrationUIFigure);
            app.CONFIRMPASSWORDEditFieldLabel.Text = {'CONFIRM '; 'PASSWORD'};
            app.CONFIRMPASSWORDEditField = uieditfield(app.registrationUIFigure, 'text');
            % Show the figure after all components are created
            app.registrationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = registration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.registrationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.registrationUIFigure)
        end
    end
end